classdef fes_leap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotPanel                      matlab.ui.container.Panel
        HandScoreUIAxes                matlab.ui.control.UIAxes
        HandUIAxes                     matlab.ui.control.UIAxes
        FESUIAxes                      matlab.ui.control.UIAxes
        ElecPanel                      matlab.ui.container.Panel
        ElectrodeMapAxes               matlab.ui.control.UIAxes
        UI_Panel                       matlab.ui.container.Panel
        ProcessDataButton              matlab.ui.control.Button
        forearmscopecmEditField        matlab.ui.control.NumericEditField
        forearmscopecmEditFieldLabel   matlab.ui.control.Label
        forearmcmEditField             matlab.ui.control.NumericEditField
        forearmcmEditFieldLabel        matlab.ui.control.Label
        genderEditField                matlab.ui.control.EditField
        genderEditFieldLabel           matlab.ui.control.Label
        hightcmEditField               matlab.ui.control.NumericEditField
        hightcmEditFieldLabel          matlab.ui.control.Label
        ageEditField                   matlab.ui.control.NumericEditField
        ageEditFieldLabel              matlab.ui.control.Label
        DispArea                       matlab.ui.control.TextArea
        modeLabel                      matlab.ui.control.Label
        Base2EditField                 matlab.ui.control.NumericEditField
        anodeEditField_2Label          matlab.ui.control.Label
        cathodeEditField_2Label        matlab.ui.control.Label
        Base1EditField                 matlab.ui.control.NumericEditField
        startbaselinedurationsecEditFieldLabel  matlab.ui.control.Label
        StimTimeEditField              matlab.ui.control.NumericEditField
        stimdurationsecEditFieldLabel  matlab.ui.control.Label
        PulseEditField                 matlab.ui.control.NumericEditField
        pulsesEditFieldLabel           matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        maxmAEditFieldLabel            matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        minmAEditFieldLabel            matlab.ui.control.Label
        endbaselinedurationsecEditFieldLabel  matlab.ui.control.Label
        CathodeEditField               matlab.ui.control.EditField
        AnodeEditField                 matlab.ui.control.EditField
        LoopNowEditField               matlab.ui.control.NumericEditField
        LoopEditField                  matlab.ui.control.NumericEditField
        StimNEditField                 matlab.ui.control.NumericEditField
        stimLabel                      matlab.ui.control.Label
        loopEditFieldLabel             matlab.ui.control.Label
        SubEditField                   matlab.ui.control.NumericEditField
        subEditFieldLabel              matlab.ui.control.Label
        COMEditField                   matlab.ui.control.EditField
        COMEditFieldLabel              matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        FESDropDown                    matlab.ui.control.DropDown
        StopButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.StateButton
        ExpButton                      matlab.ui.control.Button
        FesLamp                        matlab.ui.control.Lamp
        ManualButton                   matlab.ui.control.Button
        FESIniButton                   matlab.ui.control.Button
        PathEditField                  matlab.ui.control.EditField
        filepathEditFieldLabel         matlab.ui.control.Label
        FESdeviceDropDownLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        color_grey = [0.9,0.9,0.9];
        color_red = [0.9,0.2,0.2];
        color_green = [0.2,0.9,0.2];
        anode_list
        cathode_list
        disp_text
        FES
        FES_device
        flag_stop
    end

    methods (Access = private)
    function main(app)
        
    % Set up the data save path
    fpath.save.main = fullfile(app.PathEditField.Value, "data");
    if ~isfolder(fpath.save.main)
        mkdir(fpath.save.main);
    end
    Motion = struct();

    % Initialize parameters from app fields
    param.main.sub = app.SubEditField.Value;
    param.main.age = app.ageEditField.Value;
    param.main.hightcm = app.hightcmEditField.Value;
    param.main.gender = app.genderEditField.Value;
    param.main.forearmscopecm = app.forearmscopecmEditField.Value;
    param.main.forearmcm = app.forearmcmEditField.Value;

    % Initialize FES parameters
    P = app.FES.param;
    P.score = 0;

    %% Initialise Leap Motion and Beeps
    param.leap.fs = 100; % Hz, cycle freq limit
    param.leap.td = ceil(app.FES.param.td) + 1; % Adjust to FES time

    matleap(0); pause(1); % Wake up device
    disp_update(app, "Leap Motion initialised.");
    if app.flag_stop
        return
    end

    [BP, param.sound] = design_beep(param); % Initialize beep sounds

    total_stim = 1; % Counter for total stimulation

    % Loop through each j iteration
    for j = 1:app.LoopEditField.Value
        i = 1; % Counter for trial within each j iteration

        % Set up mode-specific parameters
        if strcmp(app.ModeDropDown.Value, "systematic")
            cathode_list = str2num(app.CathodeEditField.Value);
            anode_value = str2num(app.AnodeEditField.Value);

            P.systematic.list = cathode_list;
            P.systematic.anode = anode_value;
            param.main.trial_max = numel(cathode_list);

        elseif strcmp(app.ModeDropDown.Value, "random")
            param.main.trial_max = 31;

        elseif strcmp(app.ModeDropDown.Value, "search")
            cathode_list = str2num(app.CathodeEditField.Value); 
            anode_value = str2num(app.AnodeEditField.Value);    
            P.search.list = cathode_list;
            P.search.anode = anode_value;           
            param.main.trial_max = numel(cathode_list) + 1;

        elseif strcmp(app.ModeDropDown.Value, "optimise")
            % Ensure P.optimise structure exists
            if ~isfield(P, 'optimise')
                P.optimise = struct();
            end

            % Set fixed anode and cathodes
            P.optimise.anode = str2num(app.AnodeEditField.Value);                
            P.optimise.cathode_set = str2num(app.CathodeEditField.Value);        

            % Initialize weights and biases
            if ~isfield(P.optimise, 'weights') || isempty(P.optimise.weights)
                P.optimise.weights = rand(5, numel(P.optimise.cathode_set)); % Random weights
                P.optimise.biases = zeros(5, 1); % Zero biases
            end

            % Set learning rate
            if ~isfield(P.optimise, 'learning_rate') || isempty(P.optimise.learning_rate)
                P.optimise.learning_rate = 0.01; % Default learning rate
            end

            param.main.trial_max = numel(P.optimise.cathode_set); % Set maximum trials
        end

        if app.flag_stop
            return
        end

        % Trial loop
        while true
            param.main.trial = i;
            param.main.date = datetime('now', 'Format', 'yyyyMMddHHmmss');
            fpath.save.fname = sprintf("ReHyb_FESAutoCalib_%s_Sub%02d_Trial%03d_%s_C%s_A%s", app.ModeDropDown.Value,param.main.sub, param.main.trial, char(param.main.date),app.CathodeEditField.Value,app.AnodeEditField.Value);

            % Check for pause
            while app.PauseButton.Value == 1
                pause(0.1);
            end
            if app.flag_stop
                return
            end

            % Get electrode parameters based on mode
            D = get_elec(app, P, param, Motion);

            % Set virtual electrode parameters
            P.velec.anode = D.anode;
            P.velec.cathode = D.cathode;
            P.velec.amp = D.amp;
            P.velec.width = D.width;

            % Update UI fields
            app.AnodeEditField.Value = sprintf("[%s]", num2str(P.velec.anode));
            app.CathodeEditField.Value = sprintf("[%s]", num2str(P.velec.cathode));
            app.MaxEditField.Value = P.velec.amp;
            app.PulseEditField.Value = P.velec.width;
            UpdateElecMap(app);
            plot_fes(app);
            drawnow;

            % Ensure FES safety
            P = fes_safety(P);

            % Send FES commands
            fes_cmd(app, "elec_define", P);
            writefes(app, app.FES.cmd.(P.velec.name).velec);

            % Start stimulation
            sound(BP.start, param.sound.fs);
            disp_update(app, 'Starting FES stimulation...');
            disp_update(app, 'Starting Leap Motion recording...');
            app.StimNEditField.Value = total_stim;

            % Run trial
            Motion.Leap = run_trial(app, param.leap, P);
            disp_update(app, "FES stimulation ended");
            sound(BP.end, param.sound.fs);

            % Analyze motion data
            Motion.ana.ang = leap_getAngle(Motion.Leap);
            Motion.ana.time = (Motion.Leap.time - Motion.Leap.time(1)) / 1e6;
            Motion.ana.mean = mean(Motion.ana.ang, 3, "omitnan");
            temp.idx = Motion.ana.time < app.Base1EditField.Value;
            Motion.ana.offset = mean(Motion.ana.mean(temp.idx, :), 1);
            Motion.ana.mean_offset = Motion.ana.mean - Motion.ana.offset;

            plot_kinematics(app, Motion.ana);

            S.score(:, total_stim) = max(Motion.ana.mean_offset);
            plot_history(app, S);

            pause(3); % Break between trials

            % Store file
            param.fes = P;
            save(fullfile(fpath.save.main, fpath.save.fname), "Motion", "param");

            disp_update(app, sprintf("File saved: %s", fpath.save.fname));

            % Update trial loop
            i = i + 1;
            if i > param.main.trial_max
                break
            end

            total_stim = total_stim + 1;
        end

        app.LoopNowEditField.Value = j; % Update loop number
    end

    % Close FES and delete objects
    writefes(app, app.FES.cmd.(P.velec.name).delete);
    app.FES_device.delete;
end

% Define electrode selection function
function D = get_elec(app, P, param, Motion)

    switch app.ModeDropDown.Value

        case "random"
            % Random mode placeholder
            a = rand(32, 1);
            [~, r] = sort(a);
            D.cathode = r(1:2)';
            D.anode = r(3:4)';
            D.amp = 8;
            D.width = 300;

        case "systematic"
            % Systematic mode selection
            D.cathode = P.systematic.list(param.main.trial);
            D.anode = P.systematic.anode;
            D.amp = app.MaxEditField.Value;
            D.width = 300;

        case "search"
            % Search mode with virtual cathode first, then systematic
            if param.main.trial == 1
                
                D.cathode = P.search.list;
                D.anode = P.search.anode;                
                D.amp = app.MaxEditField.Value;
                D.width = app.PulseEditField.Value;

            else
                % Systematic testing of each individual cathode
                cathode_idx = param.main.trial - 1; 
                D.cathode = P.search.list(cathode_idx);
                D.anode = P.search.anode;
                D.amp =app.MaxEditField.Value;
                D.width = app.PulseEditField.Value;
            end

    end
end

        %% fes
        function fes_cmd(app, what, param)
            switch what
                case "initialise"
                    while true
                        app.FES_device = [];
                        disp_update(app, "searching for the FES device...")
                        dv  = bt_close(param.device);
                        if ~isempty(dv) % device found
                            try
                                disp_update(app, sprintf("FES (%s) being initialised...\n", param.device));
                                app.FES_device = bluetooth([param.device,char(13)], 1);

                                app.FES.cmd.ini = "iam DESKTOP\r\n";
                                app.FES.cmd.battery = "battery ? \r\n";
                                app.FES.cmd.fq = sprintf("freq %d\r\n", param.freq);

                                writefes(app,app.FES.cmd.ini);
                                writefes(app,app.FES.cmd.battery);
                                writefes(app,app.FES.cmd.fq);

                                t_date = char(datetime("now","Format", "dd-MM-yyyy"));
                                t_time = char(datetime("now","Format", "HH:mm:SS"));
                                app.FES.cmd.set_time = sprintf("rtc *date %s *time %s\r\n", t_date, t_time); %rtc *date 06-04-2020 *time 12:55:42
                                writefes(app, app.FES.cmd.set_time);

                                fes_cmd(app, "stim_on", param); % generate stim_on command
                                fes_cmd(app, "stim_off", param); % generate stim_off command
                                fes_cmd(app, "select", param); % generate select command
                                fes_cmd(app, "deselect", param); % generate deselect command
                                fes_cmd(app, "stim", param); % generate stim command
                                fes_cmd(app, "delete", param); % generate delete command
                                fes_cmd(app, "profile", param); % generate the profile

                                disp_update(app, "initialisation compelte.")

                                break
                            catch ME
                                disp_update(app, ['Error: ', ME.message]);
                                if app.flag_stop % safety stop
                                    return
                                end
                            end % device available
                        end % device found
                        pause(1)
                    end
                case "stim_on"
                    app.FES.cmd.stim_on = sprintf("stim on\r\n");
                case "stim_off"
                    app.FES.cmd.stim_off = sprintf("stim off\r\n");
                case "select"
                    for i = 1:size(param.velec,2)
                        velec_name = param.velec(i).name;
                        app.FES.cmd.(velec_name).select = sprintf("velec %d *selected 1\r\n",  param.velec(i).id); % 1 = activate, 0=deactivate the selected velec.
                    end
                case "deselect"
                    for i = 1:size(param.velec,2)
                        velec_name = param.velec(i).name;
                        app.FES.cmd.(velec_name).deselect = sprintf("velec %d *selected 0\r\n",  param.velec(i).id); % 1 = activate, 0=deactivate the selected velec.
                    end
                case "stim"
                    for i = 1:size(param.velec,2)
                        velec_name = param.velec(i).name;
                        app.FES.cmd.(velec_name).stim = sprintf("stim %s\r\n", velec_name); % To activate electrical stimulation only in the specified virtual electrode.
                    end
                case "elec_define"
                    for i = 1:size(param.velec,2)

                        P = param.velec(i);
                        velec_name = P.name;
                        n_anode = size(P.anode, 2);
                        n_cathode = size(P.cathode, 2);

                        S.anode = repmat("=A,", n_anode, 1);
                        S.cathode = repmat("=C,", n_cathode, 1);
                        S.equal = repmat("=", n_cathode, 1);
                        S.comma = repmat(",", n_cathode, 1);
                        S.amp_prt = repmat(num2str(P.amp,'%.1f'), n_cathode, 1);
                        S.width_prt = repmat(num2str(P.width), n_cathode, 1);

                        % Create pads string
                        temp_pads = strjoin([num2str(P.anode'), S.anode; num2str(P.cathode'), S.cathode]', '');
                        S.pads = strrep(temp_pads, ' ', '');

                        % Create amplitude string
                        temp_amp = strjoin([num2str(P.cathode'), S.equal,  S.amp_prt, S.comma]', '');
                        S.amp = strrep(temp_amp, ' ', '');

                        % Create width string
                        temp_width = strjoin([num2str(P.cathode'), S.equal,  S.width_prt, S.comma]', '');
                        S.width = strrep(temp_width, ' ', '');

                        % Assign the final command string
                        app.FES.cmd.(velec_name).velec = sprintf("velec %d *name %s *pads %s *amp %s *width %s *selected 0\r\n", ...
                            P.id, velec_name, S.pads, S.amp, S.width);
                    end
                case "profile"
                    for i = 1:size(param.velec,2)
                        P = param.velec(i);
                        velec_name = P.name;

                        writefes(app, app.FES.cmd.(velec_name).delete); % clean up the profile just in case

                        app.FES.cmd.(velec_name).profile = sprintf("sdcard cat > default/test/%s.ptn\r\n", velec_name);
                        writefes(app, app.FES.cmd.(velec_name).profile);
                        for j = 1:size(param.prof.amp,2)
                            if param.prof.ramp(j) == 1
                                app.FES.cmd.(velec_name).profile = sprintf("sdcard ed default/test/%s.ptn RAMP RAMP R %d %d %d\r\n", velec_name, param.prof.amp(j), param.prof.width(j), param.prof.time(j));
                            else
                                app.FES.cmd.(velec_name).profile = sprintf("sdcard ed default/test/%s.ptn CONST CONST R %d %d %d\r\n", velec_name, param.prof.amp(j), param.prof.width(j), param.prof.time(j));
                            end
                            writefes(app, app.FES.cmd.(velec_name).profile);
                        end
                    end
                case "delete"
                    for i = 1:size(param.velec,2)
                        velec_name = param.velec(i).name;
                        app.FES.cmd.(velec_name).delete = sprintf("sdcard rm default/test/%s.ptn\r\n", velec_name);
                    end
            end
        end

        function writefes(app, cmd)
            try
                writeline(app.FES_device, cmd); pause(0.01); %%.01
                disp_update(app, sprintf("FES sent: %s\n", cmd));

                if  app.FES_device.NumBytesAvailable> 0
                    msg = read(app.FES_device, app.FES_device.NumBytesAvailable);
                    disp_update(app, char(msg));
                end
            catch
                return
            end
        end

        %% elec visualisation
        function UpdateElecMap(app)
            app.anode_list = eval(char(app.AnodeEditField.Value));
            app.cathode_list = eval(char(app.CathodeEditField.Value));

            %% update electrode map
            cla(app.ElectrodeMapAxes);
            hold(app.ElectrodeMapAxes, 'on');

            numRows = 5;
            numCols = 7;

            % Set axes limits
            app.ElectrodeMapAxes.XLim = [0 numCols];
            app.ElectrodeMapAxes.YLim = [0 numRows];

            % Loop to create the grid
            for row = 1:numRows
                for col = 1:numCols
                    % Calculate ID for each cell
                    id = (row - 1) * numCols + col;

                    % Determine colour based on ID
                    if ismember(id, app.anode_list)
                        colour = app.color_red;  % Anode - Red
                    elseif ismember(id, app.cathode_list)
                        colour = app.color_green;  % Cathode - Green
                    else
                        colour = app.color_grey;  % Grey for other cells
                    end

                    % Draw rectangle for each cell with the determined color
                    rectangle(app.ElectrodeMapAxes, 'Position', [col-1, numRows-row, 1, 1], ...
                        'FaceColor', colour, 'EdgeColor', 'k');

                    % Plot ID number in the middle of each cell
                    text(app.ElectrodeMapAxes, col - 0.5, numRows - row + 0.5, num2str(id), ...
                        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                        'FontSize', 10, 'FontWeight', 'bold');
                end
            end

            set(app.ElectrodeMapAxes, 'XTick', [], 'YTick', [], 'XColor', 'none', 'YColor', 'none');
            axis(app.ElectrodeMapAxes, 'equal');
            hold(app.ElectrodeMapAxes, 'off');             % Release hold on the axes
        end

        function plot_fes(app)
            %%% plot fes profile
            t_max = app.Base1EditField.Value+app.Base2EditField.Value+app.StimTimeEditField.Value+1;

            p =  cumsum([app.Base1EditField.Value, 0.5, app.StimTimeEditField.Value, 0.5]);
            S.time = 0:1/app.FES.param.freq:t_max;
            S.fes = trapmf(S.time,  p)*app.MaxEditField.Value;
            plot(app.FESUIAxes,S.time,S.fes);
        end

        function plot_kinematics(app, D)
            plot(app.HandUIAxes,D.time, D.mean_offset,'-');
            legend(app.HandUIAxes, {"thumb","index","middle","ring","little"})
        end

        function plot_history(app, D)
            c = size(D.score,2);
            plot(app.HandScoreUIAxes,1:c,D.score', 'o-');
            legend(app.HandUIAxes, {"thumb","index","middle","ring","little"})
        end

        function edit_field(app, what)
            %%% control interactivity
            if what
                state_enable = "on";
            else
                state_enable = "off";
            end

            app.ExpButton.Enable = state_enable;
            app.FESDropDown.Enable = state_enable;
            app.ModeDropDown.Enable = state_enable;

            app.COMEditField.Editable = what;
            app.SubEditField.Editable = what;
            app.LoopEditField.Editable = what;
            app.AnodeEditField.Editable = what;
            app.CathodeEditField.Editable = what;
            app.MinEditField.Editable = what;
            app.MaxEditField.Editable = what;
            app.PulseEditField.Editable = what;
            app.StimTimeEditField.Editable = what;
            app.Base1EditField.Editable = what;
            app.Base2EditField.Editable = what;
            drawnow
        end

        function disp_update(app, new_txt)
            app.disp_text{8} =  app.disp_text{7};
            app.disp_text{7} =  app.disp_text{6};
            app.disp_text{6} =  app.disp_text{5};
            app.disp_text{5} =  app.disp_text{4};
            app.disp_text{4} =  app.disp_text{3};
            app.disp_text{3} =  app.disp_text{2};
            app.disp_text{2} =  app.disp_text{1};
            app.disp_text{1} = new_txt;

            app.DispArea.Value = sprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n", app.disp_text{1}, app.disp_text{2}, app.disp_text{3}, app.disp_text{4}, app.disp_text{5}, app.disp_text{6}, app.disp_text{7}, app.disp_text{8});

        end


        function D = run_trial(app, param, P)
            x = ceil(param.td * param.fs);
            nan1 = nan(x, 1);
            nan3 = nan(x, 3);
            nan4 = nan(x, 4);
            nan3d = nan(x, 3, 4);
            nan3d4 = nan(x, 4, 4);

            D.id = nan1;
            D.time = nan1;
            D.hand.id = nan1;
            D.hand.arm.joint_prev = nan3;
            D.hand.arm.joint_next= nan3;
            D.hand.arm.quat = nan4;
            D.hand.palm.pos = nan3;
            D.hand.palm.pos = nan3;
            D.hand.palm.vel = nan3;
            D.hand.palm.direction = nan3;
            D.hand.palm.quat = nan4;

            D.hand.pinch_strength = nan1;
            D.hand.pinch_distance = nan1;
            D.hand.grab_angle = nan1;
            D.hand.grab_strength = nan1;

            D.hand.digit1.pos = nan3d;
            D.hand.digit2.pos = nan3d;
            D.hand.digit3.pos = nan3d;
            D.hand.digit4.pos = nan3d;
            D.hand.digit5.pos = nan3d;

            D.hand.digit1.rot = nan3d4;
            D.hand.digit2.rot = nan3d4;
            D.hand.digit3.rot = nan3d4;
            D.hand.digit4.rot = nan3d4;
            D.hand.digit5.rot = nan3d4;

            disp_update(app, "Recording started.")

            idx = 1;
            t.hq = 1/param.fs;
            flag.fes = false;
            flag.fes_deselect = false;
            tic
            t.now = toc;
            t.prev = t.now;
            t.prev_disp = t.now;
            while t.now <= param.td % run for the trial duration
                if t.now >= t.prev + t.hq % keep to a target frequency
                    temp = matleap(1);    % get a frame

                    D.id(idx,1) = temp.id;
                    D.time(idx,1) = temp.timestamp;

                    if t.now>=app.Base1EditField.Value && ~flag.fes
                        writefes(app, app.FES.cmd.(P.velec.name).stim); % select primitive
                        flag.fes = true;
                    end
                    if t.now>=param.td-app.Base2EditField.Value && ~flag.fes_deselect
                        writefes(app, app.FES.cmd.(P.velec.name).deselect); % fes deletect
                        flag.fes_deselect = true;
                    end

                    if ~isempty(temp.hands) % tracking data available
                        D.hand.id(idx,1) = temp.hands(1).id;

                        D.hand.grab_angle(idx, 1) =  temp.hands(1).grab_angle;
                        D.hand.grab_strength(idx, 1) =  temp.hands(1).grab_strength;
                        D.hand.pinch_distance(idx, 1) =  temp.hands(1).pinch_distance;
                        D.hand.pinch_strength(idx, 1) =  temp.hands(1).pinch_strength;

                        D.hand.arm.joint_prev(idx, 1:3) = temp.hands(1).arm.prev_joint; % assume 1 hand only...
                        D.hand.arm.joint_next(idx, 1:3) = temp.hands(1).arm.next_joint;
                        D.hand.arm.quat(idx, 1:4) = temp.hands(1).arm.rotation;
                        D.hand.palm.pos(idx,1:3) = temp.hands(1).palm.position;
                        D.hand.palm.vel(idx,1:3) = temp.hands(1).palm.velocity;
                        D.hand.palm.direction(idx,1:3) = temp.hands(1).palm.direction;
                        D.hand.palm.quat(idx,1:4) = temp.hands(1).palm.orientation;

                        D.hand.digit1.pos(idx, 1:3, 1) = temp.hands(1).digits(1).bones(1).prev_joint;
                        D.hand.digit1.pos(idx, 1:3, 2) = temp.hands(1).digits(1).bones(1).next_joint;
                        D.hand.digit1.pos(idx, 1:3, 3) = temp.hands(1).digits(1).bones(2).next_joint;
                        D.hand.digit1.pos(idx, 1:3, 4) = temp.hands(1).digits(1).bones(3).next_joint;
                        D.hand.digit1.pos(idx, 1:3, 5) = temp.hands(1).digits(1).bones(4).next_joint;

                        D.hand.digit2.pos(idx, 1:3, 1) = temp.hands(1).digits(2).bones(1).prev_joint;
                        D.hand.digit2.pos(idx, 1:3, 2) = temp.hands(1).digits(2).bones(1).next_joint;
                        D.hand.digit2.pos(idx, 1:3, 3) = temp.hands(1).digits(2).bones(2).next_joint;
                        D.hand.digit2.pos(idx, 1:3, 4) = temp.hands(1).digits(2).bones(3).next_joint;
                        D.hand.digit2.pos(idx, 1:3, 5) = temp.hands(1).digits(2).bones(4).next_joint;

                        D.hand.digit3.pos(idx, 1:3, 1) = temp.hands(1).digits(3).bones(1).prev_joint;
                        D.hand.digit3.pos(idx, 1:3, 2) = temp.hands(1).digits(3).bones(1).next_joint;
                        D.hand.digit3.pos(idx, 1:3, 3) = temp.hands(1).digits(3).bones(2).next_joint;
                        D.hand.digit3.pos(idx, 1:3, 4) = temp.hands(1).digits(3).bones(3).next_joint;
                        D.hand.digit3.pos(idx, 1:3, 5) = temp.hands(1).digits(3).bones(4).next_joint;

                        D.hand.digit4.pos(idx, 1:3, 1) = temp.hands(1).digits(4).bones(1).prev_joint;
                        D.hand.digit4.pos(idx, 1:3, 2) = temp.hands(1).digits(4).bones(1).next_joint;
                        D.hand.digit4.pos(idx, 1:3, 3) = temp.hands(1).digits(4).bones(2).next_joint;
                        D.hand.digit4.pos(idx, 1:3, 4) = temp.hands(1).digits(4).bones(3).next_joint;
                        D.hand.digit4.pos(idx, 1:3, 5) = temp.hands(1).digits(4).bones(4).next_joint;

                        D.hand.digit5.pos(idx, 1:3, 1) = temp.hands(1).digits(5).bones(1).prev_joint;
                        D.hand.digit5.pos(idx, 1:3, 2) = temp.hands(1).digits(5).bones(1).next_joint;
                        D.hand.digit5.pos(idx, 1:3, 3) = temp.hands(1).digits(5).bones(2).next_joint;
                        D.hand.digit5.pos(idx, 1:3, 4) = temp.hands(1).digits(5).bones(3).next_joint;
                        D.hand.digit5.pos(idx, 1:3, 5) = temp.hands(1).digits(5).bones(4).next_joint;
                    end % tracking data available

                    %% update loop
                    idx = idx + 1 ;
                    t.prev = t.now;
                end  % target frequency

                t.now = toc; % update clock

            end
        end


        function button_control(app, what)
            app.ManualButton.Enable = what;
            app.FESIniButton.Enable = what;
            app.ExpButton.Enable = what;
            app.ProcessDataButton.Enable = what;
            drawnow
        end
            
    end

    methods (Access = private)
        
        function process_saved_data(app)
       
            data_folder = uigetdir(app.PathEditField.Value, 'Select Folder with Saved Data');
            if data_folder == 0
                disp_update(app, "No folder selected.");
                return;
            end
        
            % List all .mat files in the folder
            data_files = dir(fullfile(data_folder, 'ReHyb_FESAutoCalib_*.mat'));
            if isempty(data_files)
                disp_update(app, "No saved data files found in the selected folder.");
                return;
            end
        
            %  storage 
            all_scores = [];
            trial_info = [];
        
            % Loop through each file
            for k = 1:length(data_files)
                
                % Load the .mat file
                file_path = fullfile(data_folder, data_files(k).name);
                data = load(file_path);
        
                % Extract Motion and param fields
                Motion = data.Motion;
                param = data.param;
        
                if isfield(data, 'S') && isfield(data.S, 'score')
                    all_scores = [all_scores, max(data.S.score, [], 2)];
                end
        
                % Append trial information
                trial_info = [trial_info; param.main.trial, param.fes.velec.anode, param.fes.velec.cathode];
            end
        
            % Plot the aggregated scores
            if ~isempty(all_scores)
                figure;
                hold on;
                plot(all_scores', 'o-');
                title('Aggregated Scores from All Trials');
                xlabel('Trial Number');
                ylabel('Score (Flexion Angle)');
                legend({"Thumb", "Index", "Middle", "Ring", "Little"}, 'Location', 'Best');
                hold off;
            else
                disp_update(app, "No scores available to plot.");
            end
        
            % Display trial information
            disp_update(app, sprintf("Processed %d trials from %s", length(data_files), data_folder));
            disp("Trial Information:");
            disp(array2table(trial_info, 'VariableNames', {'Trial', 'Anode', 'Cathode'}));
            end
            function ProcessDataButtonPushed(app, event)
            % Call the process_saved_data function
            process_saved_data(app);
            end
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.anode_list = eval(char(app.AnodeEditField.Value));
            app.cathode_list = eval(char(app.CathodeEditField.Value));

            fpath.code =  fullfile(fileparts(mfilename('fullpath')));
            fpath.main = fullfile(fpath.code,"..","..");

            addpath(genpath(fpath.code));
            app.PathEditField.Value = fpath.main;

            app.disp_text{1} =  [];
            app.disp_text{2} =  [];
            app.disp_text{3} =  [];
            app.disp_text{4} =  [];
            app.disp_text{5} =  [];
            app.disp_text{6} =  [];
            app.disp_text{7} =  [];
            app.disp_text{8} =  [];

            FESIniButtonPushed(app)

            UpdateElecMap(app)

            plot_fes(app)
        end

        % Button pushed function: ExpButton
        function ExpButtonPushed(app, event)
            button_control(app, "off")
            app.flag_stop = false;

            if ishandle(app.FES_device)
                FESIniButtonPushed(app)
            end
            app.FesLamp.Color = [0,1,0];
            disp_update(app, "Experiment starting...")
            edit_field(app, false);
            app.flag_stop = false;
            app.PauseButton.Value = false; % reset
            main(app)

            disp_update(app, "Experiment complete...")
            edit_field(app, true);
            button_control(app, "on")
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            fd = fieldnames(app.FES.cmd);
            app.StopButton.Enable = false;
            drawnow
            writefes(app, app.FES.cmd.stim_off); % fes off
            writefes(app, app.FES.cmd.(fd{end}).deselect); % fes deletect
            % writefes(app, app.FES.cmd.(fd{end}).delete)
            app.FES_device.delete;
            app.flag_stop = true;
            app.FesLamp.Color = [0.8,0.8,0.8];
            button_control(app, "on")
            edit_field(app, true);
            app.StopButton.Enable = true;
            disp_update(app, "FES stopped...")
            pause(0.1)
        end

        % Value changed function: AnodeEditField
        function AnodeEditFieldValueChanged(app, event)
            app.anode_list = eval(char(app.AnodeEditField.Value));
            UpdateElecMap(app)
        end

        % Value changed function: CathodeEditField
        function CathodeEditFieldValueChanged(app, event)
            app.cathode_list = eval(char(app.CathodeEditField.Value));
            UpdateElecMap(app)
        end

        % Value changed function: StimTimeEditField
        function StimTimeEditFieldValueChanged(app, event)
            plot_fes(app)
        end

        % Value changed function: Base1EditField
        function Base1EditFieldValueChanged(app, event)
            plot_fes(app)
        end

        % Value changed function: Base2EditField
        function Base2EditFieldValueChanged(app, event)
            plot_fes(app)
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            plot_fes(app)
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            disp_update(app, "pause button pressed")
        end

        % Button pushed function: FESIniButton
        function FESIniButtonPushed(app, event)
            app.FES.param.baud = 115200;
            app.FES.param.freq = 35; % hz
            app.FES.param.com = app.COMEditField.Value; %
            app.FES.param.elec_n = 32; % electrode number
            app.FES.param.device = app.FESDropDown.Value;
            app.FES.param.amp_max = 18; % safety max

            app.FES.param.velec.name = "grip";
            app.FES.param.velec.anode = app.anode_list;  % Assign app.anode_list
            app.FES.param.velec.cathode = app.cathode_list;
            app.FES.param.velec.amp = app.MaxEditField.Value;  % Assign app.anode_list
            app.FES.param.velec.width = app.PulseEditField.Value;
            app.FES.param.velec.id = 5; % hz
            app.FES.param.prof.amp = [0, 100, 100, 0, 0]; % percent, for fes constant profile
            app.FES.param.prof.width = [0, 100, 100, 0, 0]; % percent, for fes constant profile
            app.FES.param.prof.ramp = [0, 1, 0, 1, 0]; % 0) constant, 1) ramp
            app.FES.param.prof.time = round([app.Base1EditField.Value, 0.5, app.StimTimeEditField.Value, 0.5, app.Base2EditField.Value]*1000); %msec
            app.FES.param.td = sum(app.FES.param.prof.time)/1000; %sec

            fes_cmd(app, "initialise", app.FES.param); %% initialise FES
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            button_control(app, "off")
              app.flag_stop = false;

            if ~ishandle(app.FES_device)
                FESIniButtonPushed(app)
                pause(3)
            end

            app.FesLamp.Color = [0,1,0];
            drawnow

            P = app.FES.param;
            P.velec.anode = eval(char(app.AnodeEditField.Value));
            P.velec.cathode = eval(char(app.CathodeEditField.Value));
            P = fes_safety(P); % make sure amp is bounded by the safety value
            fes_cmd(app, "elec_define", P); % generate elec definition

            disp_update(app, 'Starting FES stimulation...');
            writefes(app, app.FES.cmd.(P.velec.name).velec);
            writefes(app, app.FES.cmd.(P.velec.name).stim); % select primitive

            tic
            while toc<P.td
                pause(0.1)
                if app.flag_stop % safety
                    return
                end
            end

            writefes(app, app.FES.cmd.(P.velec.name).deselect); % fes deletect
            app.FesLamp.Color = [0.8,0.8,0.8];
            disp_update(app, "FES stimulation ended")
            button_control(app, "on")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UI_Panel = uipanel(app.UIFigure);
            app.FESdeviceDropDownLabel = uilabel(app.UI_Panel);
            app.FESdeviceDropDownLabel.Text = 'FES device';
            app.filepathEditFieldLabel = uilabel(app.UI_Panel);
            app.filepathEditFieldLabel.Text = 'file path';
            app.PathEditField = uieditfield(app.UI_Panel, 'text');
            app.FESIniButton = uibutton(app.UI_Panel, 'push');
            app.FESIniButton.ButtonPushedFcn = createCallbackFcn(app, @FESIniButtonPushed, true);
            app.FESIniButton.Text = 'initialise FES';
            app.ManualButton = uibutton(app.UI_Panel, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'manual stimulation';
            app.FesLamp = uilamp(app.UI_Panel);
            app.ExpButton = uibutton(app.UI_Panel, 'push');
            app.ExpButton.ButtonPushedFcn = createCallbackFcn(app, @ExpButtonPushed, true);
            app.ExpButton.Text = 'Experiment';
            app.PauseButton = uibutton(app.UI_Panel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'pause';
            app.StopButton = uibutton(app.UI_Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'stop';
            app.FESDropDown = uidropdown(app.UI_Panel);
            app.FESDropDown.Items = {'STIM_2_1.default', 'STIM_2_2.default'};
            app.FESDropDown.Value = 'STIM_2_1.default';
            app.ModeDropDown = uidropdown(app.UI_Panel);
            app.ModeDropDown.Items = {'search', 'systematic', 'optimise', 'random'};
            app.ModeDropDown.Value = 'systematic';
            app.COMEditFieldLabel = uilabel(app.UI_Panel);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UI_Panel, 'text');
            app.COMEditField.Value = 'COM3';
            app.subEditFieldLabel = uilabel(app.UI_Panel);
            app.subEditFieldLabel.Text = 'sub';
            app.SubEditField = uieditfield(app.UI_Panel, 'numeric');
            app.loopEditFieldLabel = uilabel(app.UI_Panel);
            app.loopEditFieldLabel.Text = 'loop';
            app.stimLabel = uilabel(app.UI_Panel);
            app.stimLabel.Text = 'stim #';
            app.StimNEditField = uieditfield(app.UI_Panel, 'numeric');
            app.StimNEditField.Editable = 'off';
            app.LoopEditField = uieditfield(app.UI_Panel, 'numeric');
            app.LoopEditField.Value = 1;
            app.LoopNowEditField = uieditfield(app.UI_Panel, 'numeric');
            app.LoopNowEditField.Editable = 'off';
            app.AnodeEditField = uieditfield(app.UI_Panel, 'text');
            app.AnodeEditField.ValueChangedFcn = createCallbackFcn(app, @AnodeEditFieldValueChanged, true);
            app.AnodeEditField.Value = '[16]';
            app.CathodeEditField = uieditfield(app.UI_Panel, 'text');
            app.CathodeEditField.ValueChangedFcn = createCallbackFcn(app, @CathodeEditFieldValueChanged, true);
            app.CathodeEditField.Value = '[9,8,7,10,12,13,11,6,5,4,3,2,1,18,17,20,19,22,21,24,23,26,25,28,27,30,32,29,31]';
            app.endbaselinedurationsecEditFieldLabel = uilabel(app.UI_Panel);
            app.endbaselinedurationsecEditFieldLabel.Text = 'end baseline duration (sec)';
            app.minmAEditFieldLabel = uilabel(app.UI_Panel);
            app.minmAEditFieldLabel.Text = 'min mA';
            app.MinEditField = uieditfield(app.UI_Panel, 'numeric');
            app.MinEditField.Value = 5;
            app.maxmAEditFieldLabel = uilabel(app.UI_Panel);
            app.maxmAEditFieldLabel.Text = 'max mA';
            app.MaxEditField = uieditfield(app.UI_Panel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 10;
            app.pulsesEditFieldLabel = uilabel(app.UI_Panel);
            app.pulsesEditFieldLabel.Text = 'pulse (μs)';
            app.PulseEditField = uieditfield(app.UI_Panel, 'numeric');
            app.PulseEditField.Value = 300;
            app.stimdurationsecEditFieldLabel = uilabel(app.UI_Panel);
            app.stimdurationsecEditFieldLabel.Text = 'stim duration (sec)';
            app.StimTimeEditField = uieditfield(app.UI_Panel, 'numeric');
            app.StimTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StimTimeEditFieldValueChanged, true);
            app.StimTimeEditField.Value = 1;
            app.startbaselinedurationsecEditFieldLabel = uilabel(app.UI_Panel);
            app.startbaselinedurationsecEditFieldLabel.Text = 'start baseline duration (sec)';
            app.Base1EditField = uieditfield(app.UI_Panel, 'numeric');
            app.Base1EditField.ValueChangedFcn = createCallbackFcn(app, @Base1EditFieldValueChanged, true);
            app.Base1EditField.Value = 1;
            app.cathodeEditField_2Label = uilabel(app.UI_Panel);
            app.cathodeEditField_2Label.Text = 'cathode';
            app.anodeEditField_2Label = uilabel(app.UI_Panel);
            app.anodeEditField_2Label.Text = 'anode';
            app.Base2EditField = uieditfield(app.UI_Panel, 'numeric');
            app.Base2EditField.ValueChangedFcn = createCallbackFcn(app, @Base2EditFieldValueChanged, true);
            app.Base2EditField.Value = 1;
            app.modeLabel = uilabel(app.UI_Panel);
            app.modeLabel.Text = 'mode';
            app.DispArea = uitextarea(app.UI_Panel);
            app.DispArea.Editable = 'off';
            app.ageEditFieldLabel = uilabel(app.UI_Panel);
            app.ageEditFieldLabel.Text = 'age';
            app.ageEditField = uieditfield(app.UI_Panel, 'numeric');
            app.ageEditField.RoundFractionalValues = 'on';
            app.hightcmEditFieldLabel = uilabel(app.UI_Panel);
            app.hightcmEditFieldLabel.Text = 'hight(cm)';
            app.hightcmEditField = uieditfield(app.UI_Panel, 'numeric');
            app.hightcmEditField.AllowEmpty = 'on';
            app.genderEditFieldLabel = uilabel(app.UI_Panel);
            app.genderEditFieldLabel.Text = 'gender';
            app.genderEditField = uieditfield(app.UI_Panel, 'text');
            app.genderEditField.InputType = 'letters';
            app.genderEditField.Placeholder = 'm/f/d';
            app.forearmcmEditFieldLabel = uilabel(app.UI_Panel);
            app.forearmcmEditFieldLabel.Text = 'forearm(cm)';
            app.forearmcmEditField = uieditfield(app.UI_Panel, 'numeric');
            app.forearmcmEditField.AllowEmpty = 'on';
            app.forearmscopecmEditFieldLabel = uilabel(app.UI_Panel);
            app.forearmscopecmEditFieldLabel.Text = 'forearm scope (cm)';
            app.forearmscopecmEditField = uieditfield(app.UI_Panel, 'numeric');
            app.forearmscopecmEditField.AllowEmpty = 'on';
            app.ProcessDataButton = uibutton(app.UI_Panel, 'push');
            app.ProcessDataButton.Text = 'Process Data';
            app.ElecPanel = uipanel(app.UIFigure);
            app.ElectrodeMapAxes = uiaxes(app.ElecPanel);
            title(app.ElectrodeMapAxes, 'Electdoe map')
            app.ElectrodeMapAxes.XTick = [];
            app.ElectrodeMapAxes.XTickLabel = {};
            app.ElectrodeMapAxes.YTick = [];
            app.ElectrodeMapAxes.YTickLabel = {};
            app.ElectrodeMapAxes.ZTick = [];
            app.ElectrodeMapAxes.ZTickLabel = '';
            app.PlotPanel = uipanel(app.UIFigure);
            app.FESUIAxes = uiaxes(app.PlotPanel);
            xlabel(app.FESUIAxes, 'time (sec)')
            ylabel(app.FESUIAxes, 'fes (mA)')
            app.HandUIAxes = uiaxes(app.PlotPanel);
            xlabel(app.HandUIAxes, 'time (sec)')
            ylabel(app.HandUIAxes, 'angle (deg)')
            app.HandScoreUIAxes = uiaxes(app.PlotPanel);
            xlabel(app.HandScoreUIAxes, 'iteration')
            ylabel(app.HandScoreUIAxes, 'angle (deg)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fes_leap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end